classdef GP_AsynAggregation_RemoteGP < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		RemoteGPRepeatableUIFigure      matlab.ui.Figure
		GPNrDropDown                    matlab.ui.control.DropDown
		DataSetDropDown                 matlab.ui.control.DropDown
		StatusInfoTextArea              matlab.ui.control.TextArea
		StatusInfoTextAreaLabel         matlab.ui.control.Label
		GPDataQuantityEditField         matlab.ui.control.NumericEditField
		GPDataQuantityEditFieldLabel    matlab.ui.control.Label
		ReceivedQuantityEditField       matlab.ui.control.NumericEditField
		ReceivedQuantityEditFieldLabel  matlab.ui.control.Label
		LoGGPComputationTimeGauge       matlab.ui.control.LinearGauge
		LoGGPComputationTimeGaugeLabel  matlab.ui.control.Label
		UDPSendTimeGauge                matlab.ui.control.LinearGauge
		UDPSendTimeGaugeLabel           matlab.ui.control.Label
		UDPReadTimeGauge                matlab.ui.control.LinearGauge
		UDPReadTimeGaugeLabel           matlab.ui.control.Label
		ListeningFrequencyEditField     matlab.ui.control.NumericEditField
		ListeningFrequencyEditFieldLabel  matlab.ui.control.Label
		LoGGPReadyLamp                  matlab.ui.control.Lamp
		LoGGPReadyLampLabel             matlab.ui.control.Label
		InitializeLogGPSwitch           matlab.ui.control.RockerSwitch
		MaxLocalGPQuantityEditField     matlab.ui.control.NumericEditField
		MaxLocalGPQuantityLabel         matlab.ui.control.Label
		MaxLocalDataQuantityEditField   matlab.ui.control.NumericEditField
		MaxLocalDataQuantityEditFieldLabel  matlab.ui.control.Label
		SigmaFEditField                 matlab.ui.control.NumericEditField
		SigmaFEditFieldLabel            matlab.ui.control.Label
		SigmaNEditField                 matlab.ui.control.NumericEditField
		SigmaNEditFieldLabel            matlab.ui.control.Label
		DimofYEditField                 matlab.ui.control.NumericEditField
		DimofYEditFieldLabel            matlab.ui.control.Label
		DimofXEditField                 matlab.ui.control.NumericEditField
		DimofXEditFieldLabel            matlab.ui.control.Label
		SendConnectionLamp              matlab.ui.control.Lamp
		SendConnectionLampLabel         matlab.ui.control.Label
		ReadConnectionLamp              matlab.ui.control.Lamp
		ReadConnectionLampLabel         matlab.ui.control.Label
		PorttosendEditField             matlab.ui.control.NumericEditField
		PorttosendEditFieldLabel        matlab.ui.control.Label
		PorttoreadEditField             matlab.ui.control.NumericEditField
		PorttoreadEditFieldLabel        matlab.ui.control.Label
		IPtosendEditField               matlab.ui.control.EditField
		IPtosendEditFieldLabel          matlab.ui.control.Label
		IPtoreadEditField               matlab.ui.control.EditField
		IPtoreadEditFieldLabel          matlab.ui.control.Label
		StartButton                     matlab.ui.control.StateButton
		LoGGPSettingLabel               matlab.ui.control.Label
		UDPConnectSwitch                matlab.ui.control.RockerSwitch
		UseDefaultHyperparametersButton  matlab.ui.control.Button
		SigmaLTable                     matlab.ui.control.Table
		UseDefaultPortforSendButton     matlab.ui.control.Button
		UseDefaultPortforReadButton     matlab.ui.control.Button
		UseLocalIPforSendButton         matlab.ui.control.Button
		UseLocalIPforReadButton         matlab.ui.control.Button
		UDPforWritingLabel              matlab.ui.control.Label
		UDPforReadingLabel              matlab.ui.control.Label
		InitializeReomoteGPButton       matlab.ui.control.Button
	end

	
	properties (Access = private)
		udp_read;
		udp_send;
		LoGGP;
	end
	
	properties (Access = public)
		RemoteStartButtonValue = [];
	end
	
	methods (Access = public)
		
		function SetDefaultGPParameters(app)
			DataSetName = app.DataSetDropDown.Value;
			load(['DataSet_',DataSetName,'.mat'], ...
				'HyperparameterSet','x_dim');
			app.DimofXEditField.Value = x_dim;
			app.DimofYEditField.Value = 1;
			app.SigmaFEditField.Value = HyperparameterSet{1}.SigmaF;
			app.SigmaNEditField.Value = HyperparameterSet{1}.SigmaN;
			app.SigmaLTable.Data = reshape(HyperparameterSet{1}.SigmaL,[],1);

			HyperparameterTableRowNameCell = cell(x_dim,1);
			for HyperparameterNr = 1:x_dim
				HyperparameterTableRowNameCell{HyperparameterNr} = num2str(HyperparameterNr);
			end
			app.SigmaLTable.RowName = HyperparameterTableRowNameCell;
			drawnow;
		end
		
		function InitializeLogGP(app)
			x_dim = app.DimofXEditField.Value;
			y_dim = app.DimofYEditField.Value;
			SigmaN = app.SigmaNEditField.Value;
			SigmaF = app.SigmaFEditField.Value;
			SigmaL = app.SigmaLTable.Data;
			Max_LocalGP_DataQuantity = app.MaxLocalDataQuantityEditField.Value;
			Max_LocalGP_Quantity = app.MaxLocalGPQuantityEditField.Value;

			app.LoGGP = LoG_GP_MultiOutput(Max_LocalGP_DataQuantity,Max_LocalGP_Quantity, ...
				x_dim,y_dim,SigmaN,SigmaF,SigmaL);

			app.LoGGP.AggregationMethod = 'GPOE';
			app.LoGGP.o_ratio = 1/100;

			app.LoGGPReadyLamp.Color = [0.00,1.00,0.00];

			app.LockGPParameters;

			if strcmpi(app.UDPConnectSwitch.Value,'On') && ...
					strcmpi(app.InitializeLogGPSwitch.Value,'On')
				app.StartButton.Enable = true;
				app.StartButton.Value = false;
			end
		end

		function DeleteLogGP(app)
			app.LoGGP = [];
			app.LoGGPReadyLamp.Color = [1.00,0.00,0.00];

			app.UnlockGPParameters;
		end

		function InitializeUDPPort(app)
			% Build Connection for UDP read
			try
				app.udp_read = udpport('datagram','IPV4','EnablePortSharing',true, ...
					'LocalHost',app.IPtoreadEditField.Value, ...
					'LocalPort',app.PorttoreadEditField.Value);
				configureMulticast(app.udp_read, '226.0.0.1');
				app.ReadConnectionLamp.Color = [0.00,1.00,0.00];
				flag_read_connected = true;
			catch
				app.ReadConnectionLamp.Color = [1.00,0.00,0.00];
				flag_read_connected = false;
			end
			% Build Connection for UDP send
			try
				app.udp_send = udpport('IPV4','EnablePortSharing',true, ...
					'LocalHost',app.IPtoreadEditField.Value);
				app.udp_send.UserData = app.PorttosendEditField.Value;
				app.SendConnectionLamp.Color = [0.00,1.00,0.00];
				flag_send_connected = true;
			catch
				app.SendConnectionLamp.Color = [1.00,0.00,0.00];
				flag_send_connected = false;
			end

			if ~flag_read_connected || ~flag_send_connected
				app.UDPConnectSwitch.Value = 'Off';
			else
				app.LockUDPParameters;
			end

			if strcmpi(app.UDPConnectSwitch.Value,'On') && ...
					strcmpi(app.InitializeLogGPSwitch.Value,'On')
				app.StartButton.Enable = true;
				app.StartButton.Value = false;
			end
		end

		function DeleteUDPPort(app)
			try
				flush(app.udp_read,'output');
				delete(app.udp_read);
				app.ReadConnectionLamp.Color = [1.00,0.00,0.00];
			catch
			end

			try
				flush(app.udp_send,'output');
				delete(app.udp_send);
				app.SendConnectionLamp.Color = [1.00,0.00,0.00];
			catch
			end

			app.UnlockUDPParameters;

			app.StartButton.Enable = false;
			app.StartButton.Value = false;
		end
		
		function StartRemoteGP(app)
			Timer_General = tic;
			t_now = toc(Timer_General);
			ListeningFrequency = app.ListeningFrequencyEditField.Value;
			ListeningTimeInterval = 1 / ListeningFrequency;
			app.StatusInfoTextArea.Value = 'Prepare into while-loop';
			drawnow;
			while true
				t_new = toc(Timer_General);
				if t_new - t_now >= ListeningTimeInterval
					t_now = t_new;

					if app.udp_read.NumDatagramsAvailable > 0
						app.StatusInfoTextArea.Value = 'Prepare into UDP read';
						drawnow;
						Timer_Read = tic;
						received_data = read(app.udp_read,app.udp_read.NumDatagramsAvailable,'single');
						received_data = received_data(end).Data';
						app.UDPReadTimeGauge.Value = toc(Timer_Read);

						app.StatusInfoTextArea.Value = 'Prepare into GP prediction';
						drawnow;
						
						if numel(received_data) < 2
							app.UDPReadTimeGauge.Value = 0;
							app.LoGGPComputationTimeGauge.Value = 0;
							app.UDPSendTimeGauge.Value = 0;
							app.ReceivedQuantityEditField.Value = 0;
							app.LoGGP = [];
							x_dim = app.DimofXEditField.Value;
							y_dim = app.DimofYEditField.Value;
							SigmaN = app.SigmaNEditField.Value;
							SigmaF = app.SigmaFEditField.Value;
							SigmaL = app.SigmaLTable.Data;
							Max_LocalGP_DataQuantity = app.MaxLocalDataQuantityEditField.Value;
							Max_LocalGP_Quantity = app.MaxLocalGPQuantityEditField.Value;

							app.LoGGP = LoG_GP_MultiOutput(Max_LocalGP_DataQuantity,Max_LocalGP_Quantity, ...
								x_dim,y_dim,SigmaN,SigmaF,SigmaL);

							app.LoGGP.AggregationMethod = 'GPOE';
							app.LoGGP.o_ratio = 1/100;
						else
							Timer_LoGGP = tic;
							received_x = received_data(1:end-2);
							received_y = received_data(end-1);
							received_t = received_data(end);
							[mu,var,~,~] = app.LoGGP.predict(received_x,received_y);
							app.LoGGP.update(received_x,received_y);
							app.LoGGPComputationTimeGauge.Value = toc(Timer_LoGGP);

							app.StatusInfoTextArea.Value = 'Prepare into UDP send';
							drawnow;
							Timer_Send = tic;
							Sent_Data = [mu;var;received_x;received_t];
							write(app.udp_send,Sent_Data,'single', ...
								app.IPtosendEditField.Value,app.PorttosendEditField.Value);
							app.UDPSendTimeGauge.Value = toc(Timer_Send);

							app.ReceivedQuantityEditField.Value = app.ReceivedQuantityEditField.Value + 1;
							app.GPDataQuantityEditField.Value = app.LoGGP.DataQuantity;
						end
					end

					app.StatusInfoTextArea.Value = 'Prepare into remote control';
					drawnow;
					if ~isempty(app.RemoteStartButtonValue)
						value = app.RemoteStartButtonValue;
					else
						value = app.StartButton.Value;
					end

					app.StatusInfoTextArea.Value = 'Prepare into break check';
					if value == false
						app.UDPReadTimeGauge.Value = 0;
						app.LoGGPComputationTimeGauge.Value = 0;
						app.UDPSendTimeGauge.Value = 0;
						app.ReceivedQuantityEditField.Value = 0;
						break;
					end
				end
				drawnow;
			end
		end
	end
	
	methods (Access = private)
		
		function LockGPParameters(app)
			app.DimofXEditField.Editable = false;
			app.DimofYEditField.Editable = false;
			app.SigmaFEditField.Editable = false;
			app.SigmaNEditField.Editable = false;
			app.SigmaLTable.ColumnEditable = false;
			app.MaxLocalDataQuantityEditField.Editable = false;
			app.MaxLocalGPQuantityEditField.Editable = false;
			app.UseDefaultHyperparametersButton.Enable = false;
		end

		function UnlockGPParameters(app)
			app.DimofXEditField.Editable = true;
			app.DimofYEditField.Editable = true;
			app.SigmaFEditField.Editable = true;
			app.SigmaNEditField.Editable = true;
			app.SigmaLTable.ColumnEditable = true;
			app.MaxLocalDataQuantityEditField.Editable = true;
			app.MaxLocalGPQuantityEditField.Editable = true;
			app.UseDefaultHyperparametersButton.Enable = true;

			app.StartButton.Enable = false;
			app.StartButton.Value = false;
		end

		function LockUDPParameters(app)
			app.InitializeReomoteGPButton.Enable = false;
			app.IPtoreadEditField.Editable = false;
			app.PorttoreadEditField.Editable = false;
			app.IPtosendEditField.Editable = false;
			app.PorttosendEditField.Editable = false;
			app.UseLocalIPforReadButton.Enable = false;
			app.UseDefaultPortforReadButton.Enable = false;
			app.UseLocalIPforSendButton.Enable = false;
			app.UseDefaultPortforSendButton.Enable = false;
		end

		function UnlockUDPParameters(app)
			app.InitializeReomoteGPButton.Enable = true;
			app.IPtoreadEditField.Editable = true;
			app.PorttoreadEditField.Editable = true;
			app.IPtosendEditField.Editable = true;
			app.PorttosendEditField.Editable = true;
			app.UseLocalIPforReadButton.Enable = true;
			app.UseDefaultPortforReadButton.Enable = true;
			app.UseLocalIPforSendButton.Enable = true;
			app.UseDefaultPortforSendButton.Enable = true;
		end
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			try
				HyperparameterQuantity = app.DimofXEditField.Value;
				HyperparameterTableRowNameCell = cell(HyperparameterQuantity,1);
				for HyperparameterNr = 1:HyperparameterQuantity
					HyperparameterTableRowNameCell{HyperparameterNr} = num2str(HyperparameterNr);
				end
				app.SigmaLTable.RowName = HyperparameterTableRowNameCell;
				app.SigmaLTable.Data = zeros(HyperparameterQuantity,1);

				app.SetDefaultGPParameters;


				[~, result] = system('ipconfig');
				result = strsplit(result,'\n');
				for ResultNr = 1:numel(result)
					if strfind(result{ResultNr},'IPv4')
						break;
					end
				end
				result = strsplit(result{ResultNr},': ');
				LocalIP = result{end};
				app.IPtoreadEditField.Value = LocalIP;
				app.IPtosendEditField.Value = LocalIP;

				drawnow;
			catch

			end

			
		end

		% Button pushed function: InitializeReomoteGPButton
		function InitializeReomoteGPButtonPushed(app, event)
			GPNr = round(str2double(app.GPNrDropDown.Value));
			app.PorttosendEditField.Value = 8050 + GPNr * 100;
			switch app.GPNrDropDown.Value
				case '1'
					app.MaxLocalDataQuantityEditField.Value = 100;
					app.MaxLocalGPQuantityEditField.Value = 100;
				case '2'
					app.MaxLocalDataQuantityEditField.Value = 50;
					app.MaxLocalGPQuantityEditField.Value = 200;
				case '3'
					app.MaxLocalDataQuantityEditField.Value = 500;
					app.MaxLocalGPQuantityEditField.Value = 50;
				case '4'
					app.MaxLocalDataQuantityEditField.Value = 1000;
					app.MaxLocalGPQuantityEditField.Value = 20;
			end
		end

		% Button pushed function: UseLocalIPforReadButton
		function UseLocalIPforReadButtonPushed(app, event)
			[~, result] = system('ipconfig');
			result = strsplit(result,'\n');
			for ResultNr = 1:numel(result)
				if strfind(result{ResultNr},'IPv4')
					break;
				end
			end
			result = strsplit(result{ResultNr},': ');
			LocalIP = result{end};
			app.IPtoreadEditField.Value = LocalIP;
		end

		% Button pushed function: UseLocalIPforSendButton
		function UseLocalIPforSendButtonPushed(app, event)
			[~, result] = system('ipconfig');
			result = strsplit(result,'\n');
			for ResultNr = 1:numel(result)
				if strfind(result{ResultNr},'IPv4')
					break;
				end
			end
			result = strsplit(result{ResultNr},': ');
			LocalIP = result{end};
			app.IPtosendEditField.Value = LocalIP;
		end

		% Button pushed function: UseDefaultPortforReadButton
		function UseDefaultPortforReadButtonPushed(app, event)
			app.PorttoreadEditField.Value = 8000;
		end

		% Button pushed function: UseDefaultPortforSendButton
		function UseDefaultPortforSendButtonPushed(app, event)
			app.PorttosendEditField.Value = 8050;
		end

		% Value changed function: UDPConnectSwitch
		function UDPConnectSwitchValueChanged(app, event)
			value = app.UDPConnectSwitch.Value;
			if strcmpi(value,'On')
				app.InitializeUDPPort;
			else
				app.DeleteUDPPort;
			end
		end

		% Button pushed function: UseDefaultHyperparametersButton
		function UseDefaultHyperparametersButtonPushed(app, event)
			app.SetDefaultGPParameters;
		end

		% Value changed function: DimofXEditField
		function DimofXEditFieldValueChanged(app, event)
			value = app.DimofXEditField.Value;
			HyperparameterQuantity = value;
			HyperparameterTableRowNameCell = cell(HyperparameterQuantity,1);
			for HyperparameterNr = 1:HyperparameterQuantity
				HyperparameterTableRowNameCell{HyperparameterNr} = num2str(HyperparameterNr);
			end
			app.SigmaLTable.RowName = HyperparameterTableRowNameCell;
			app.SigmaLTable.Data = zeros(HyperparameterQuantity,1);
			drawnow;
		end

		% Value changed function: InitializeLogGPSwitch
		function InitializeLogGPSwitchValueChanged(app, event)
			if strcmpi(app.InitializeLogGPSwitch.Value,'On')
				app.InitializeLogGP;
			else
				app.DeleteLogGP;
			end
		end

		% Value changed function: StartButton
		function StartButtonValueChanged(app, event)
			if app.StartButton.Value
				app.StartRemoteGP;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.RemoteGPRepeatableUIFigure = uifigure('Visible', 'off');
			app.RemoteGPRepeatableUIFigure.Name = 'Remote GP Repeatable';
			app.InitializeReomoteGPButton = uibutton(app.RemoteGPRepeatableUIFigure, 'push');
			app.InitializeReomoteGPButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeReomoteGPButtonPushed, true);
			app.InitializeReomoteGPButton.Text = 'Initialize Reomote GP';
			app.UDPforReadingLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.UDPforReadingLabel.Text = 'UDP for Reading';
			app.UDPforWritingLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.UDPforWritingLabel.Text = 'UDP for Writing';
			app.UseLocalIPforReadButton = uibutton(app.RemoteGPRepeatableUIFigure, 'push');
			app.UseLocalIPforReadButton.ButtonPushedFcn = createCallbackFcn(app, @UseLocalIPforReadButtonPushed, true);
			app.UseLocalIPforReadButton.Text = 'Use Local IP for Read';
			app.UseLocalIPforSendButton = uibutton(app.RemoteGPRepeatableUIFigure, 'push');
			app.UseLocalIPforSendButton.ButtonPushedFcn = createCallbackFcn(app, @UseLocalIPforSendButtonPushed, true);
			app.UseLocalIPforSendButton.Text = 'Use Local IP for Send';
			app.UseDefaultPortforReadButton = uibutton(app.RemoteGPRepeatableUIFigure, 'push');
			app.UseDefaultPortforReadButton.ButtonPushedFcn = createCallbackFcn(app, @UseDefaultPortforReadButtonPushed, true);
			app.UseDefaultPortforReadButton.Text = 'Use Default Port for Read';
			app.UseDefaultPortforSendButton = uibutton(app.RemoteGPRepeatableUIFigure, 'push');
			app.UseDefaultPortforSendButton.ButtonPushedFcn = createCallbackFcn(app, @UseDefaultPortforSendButtonPushed, true);
			app.UseDefaultPortforSendButton.Text = 'Use Default Port for Send';
			app.SigmaLTable = uitable(app.RemoteGPRepeatableUIFigure);
			app.SigmaLTable.ColumnName = {'Sigma L'};
			app.SigmaLTable.RowName = {};
			app.SigmaLTable.ColumnEditable = true;
			app.UseDefaultHyperparametersButton = uibutton(app.RemoteGPRepeatableUIFigure, 'push');
			app.UseDefaultHyperparametersButton.ButtonPushedFcn = createCallbackFcn(app, @UseDefaultHyperparametersButtonPushed, true);
			app.UseDefaultHyperparametersButton.Text = 'Use Default Hyperparameters';
			app.UDPConnectSwitch = uiswitch(app.RemoteGPRepeatableUIFigure, 'rocker');
			app.UDPConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @UDPConnectSwitchValueChanged, true);
			app.LoGGPSettingLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.LoGGPSettingLabel.Text = 'LoG-GP Setting';
			app.StartButton = uibutton(app.RemoteGPRepeatableUIFigure, 'state');
			app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
			app.StartButton.Enable = 'off';
			app.StartButton.Text = 'Start';
			app.IPtoreadEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.IPtoreadEditFieldLabel.Text = 'IP to read';
			app.IPtoreadEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'text');
			app.IPtoreadEditField.Value = '0.0.0.0';
			app.IPtosendEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.IPtosendEditFieldLabel.Text = 'IP to send';
			app.IPtosendEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'text');
			app.IPtosendEditField.Value = '0.0.0.0';
			app.PorttoreadEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.PorttoreadEditFieldLabel.Text = 'Port to read';
			app.PorttoreadEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.PorttoreadEditField.Value = 8000;
			app.PorttosendEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.PorttosendEditFieldLabel.Text = 'Port to send';
			app.PorttosendEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.PorttosendEditField.Value = 8150;
			app.ReadConnectionLampLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.ReadConnectionLampLabel.Text = 'Read Connection';
			app.ReadConnectionLamp = uilamp(app.RemoteGPRepeatableUIFigure);
			app.SendConnectionLampLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.SendConnectionLampLabel.Text = 'Send Connection';
			app.SendConnectionLamp = uilamp(app.RemoteGPRepeatableUIFigure);
			app.DimofXEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.DimofXEditFieldLabel.Text = 'Dim. of X';
			app.DimofXEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.DimofXEditField.ValueChangedFcn = createCallbackFcn(app, @DimofXEditFieldValueChanged, true);
			app.DimofXEditField.Value = 1;
			app.DimofYEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.DimofYEditFieldLabel.Text = 'Dim. of Y';
			app.DimofYEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.DimofYEditField.Value = 1;
			app.SigmaNEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.SigmaNEditFieldLabel.Text = 'Sigma N';
			app.SigmaNEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.SigmaFEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.SigmaFEditFieldLabel.Text = 'Sigma F';
			app.SigmaFEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.MaxLocalDataQuantityEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.MaxLocalDataQuantityEditFieldLabel.Text = 'Max. Local Data Quantity';
			app.MaxLocalDataQuantityEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.MaxLocalDataQuantityEditField.Value = 100;
			app.MaxLocalGPQuantityLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.MaxLocalGPQuantityLabel.Text = 'Max. Local GP Quantity';
			app.MaxLocalGPQuantityEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.MaxLocalGPQuantityEditField.Value = 100;
			app.InitializeLogGPSwitch = uiswitch(app.RemoteGPRepeatableUIFigure, 'rocker');
			app.InitializeLogGPSwitch.ValueChangedFcn = createCallbackFcn(app, @InitializeLogGPSwitchValueChanged, true);
			app.LoGGPReadyLampLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.LoGGPReadyLampLabel.Text = 'LoG-GP Ready';
			app.LoGGPReadyLamp = uilamp(app.RemoteGPRepeatableUIFigure);
			app.ListeningFrequencyEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.ListeningFrequencyEditFieldLabel.Text = 'Listening Frequency';
			app.ListeningFrequencyEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.ListeningFrequencyEditField.Value = 1000;
			app.UDPReadTimeGaugeLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.UDPReadTimeGaugeLabel.Text = 'UDP Read Time';
			app.UDPReadTimeGauge = uigauge(app.RemoteGPRepeatableUIFigure, 'linear');
			app.UDPReadTimeGauge.Limits = [0 0.05];
			app.UDPSendTimeGaugeLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.UDPSendTimeGaugeLabel.Text = 'UDP Send Time';
			app.UDPSendTimeGauge = uigauge(app.RemoteGPRepeatableUIFigure, 'linear');
			app.UDPSendTimeGauge.Limits = [0 0.05];
			app.LoGGPComputationTimeGaugeLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.LoGGPComputationTimeGaugeLabel.Text = 'LoGGP Computation Time';
			app.LoGGPComputationTimeGauge = uigauge(app.RemoteGPRepeatableUIFigure, 'linear');
			app.LoGGPComputationTimeGauge.Limits = [0 0.05];
			app.ReceivedQuantityEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.ReceivedQuantityEditFieldLabel.Text = 'Received Quantity';
			app.ReceivedQuantityEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.GPDataQuantityEditFieldLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.GPDataQuantityEditFieldLabel.Text = 'GP Data Quantity';
			app.GPDataQuantityEditField = uieditfield(app.RemoteGPRepeatableUIFigure, 'numeric');
			app.StatusInfoTextAreaLabel = uilabel(app.RemoteGPRepeatableUIFigure);
			app.StatusInfoTextAreaLabel.Text = 'Status Info';
			app.StatusInfoTextArea = uitextarea(app.RemoteGPRepeatableUIFigure);
			app.DataSetDropDown = uidropdown(app.RemoteGPRepeatableUIFigure);
			app.DataSetDropDown.Items = {'SARCOS', 'KIN40K', 'POL', 'PUMADYN32NM', 'Control', 'Toy'};
			app.DataSetDropDown.Value = 'SARCOS';
			app.GPNrDropDown = uidropdown(app.RemoteGPRepeatableUIFigure);
			app.GPNrDropDown.Items = {'1', '2', '3', '4'};
			app.GPNrDropDown.Value = '1';
			% Show the figure after all components are created
			app.RemoteGPRepeatableUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = GP_AsynAggregation_RemoteGP

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.RemoteGPRepeatableUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.RemoteGPRepeatableUIFigure)
		end
	end
end